classdef BAICA_Communication_Module_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BAICACommunicationModuleUIFigure  matlab.ui.Figure
        Image                         matlab.ui.control.Image
        Button                        matlab.ui.control.Button
        PerguntaDropDown              matlab.ui.control.DropDown
        TabGroup3                     matlab.ui.container.TabGroup
        GrficoTab                     matlab.ui.container.Tab
        ax_graph                      matlab.ui.control.UIAxes
        Tabela                        matlab.ui.container.Tab
        table                         matlab.ui.control.Table
        FLAMENGOBASKETBALLFLAMENGOLabel_3  matlab.ui.control.Label
        FLAMENGOBASKETBALLFLAMENGOLabel_2  matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        CHATcomoBAICATextArea         matlab.ui.control.TextArea
        CHATcomoBAICATextAreaLabel    matlab.ui.control.Label
        CHATcomoBAICATextAreaLabel_2  matlab.ui.control.Label
        CHATcomoBAICATextAreaLabel_3  matlab.ui.control.Label
        user_field                    matlab.ui.control.EditField
    end

    
    properties (Access = public)
        control     GuiControl % control class
    end
    
    methods (Access = private)        
        % handle push button on dialog mode
        function Dialog_Mode_CallBack(app)
            user_input = strtrim((app.PerguntaDropDown.Value));
            app.control.UpdateChat(user_input,app.control.GetUserName());
            app.control.ShowAnswer(string(app.PerguntaDropDown.Value));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize graph and table properties
            app.ax_graph.Toolbar.Visible = 'off';
            app.ax_graph.Interactions = [];
            app.ax_graph.XAxis.Visible = 'off';
            app.ax_graph.YAxis.Visible = 'off';
            app.table.ColumnName = [];
            % Init Gui Control 
            app.control = GuiControl(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            user_input = strtrim((app.user_field.Value));
            app.control.CheckValidUserInput(user_input);
            app.user_field.Value = '';
            if (app.control.IsUserAutorized())
                app.control.InitDialogMode();
                app.Button.ButtonPushedFcn = createCallbackFcn(app, @Dialog_Mode_CallBack);
                delete(app.user_field);
                app.CHATcomoBAICATextAreaLabel_3.Text = 'O que você quer saber?';
            end
        end

        % Value changing function: user_field
        function user_fieldValueChanging(app, event)
            if (app.control.IsPasswordAllowed())
                changingValue = event.Value;
                app.control.BuildPassowrd(changingValue(end));
                app.user_field.Value = repmat('*',1,length(changingValue));
                drawnow;
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BAICACommunicationModuleUIFigure = uifigure('Visible', 'off');
            app.BAICACommunicationModuleUIFigure.Name = 'BAICA Communication Module';
            app.Image = uiimage(app.BAICACommunicationModuleUIFigure);
            app.Image.ImageSource = 'gradient.png';
            app.Button = uibutton(app.BAICACommunicationModuleUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '>';
            app.PerguntaDropDown = uidropdown(app.BAICACommunicationModuleUIFigure);
            app.PerguntaDropDown.Items = {' '};
            app.PerguntaDropDown.Editable = 'on';
            app.PerguntaDropDown.Value = ' ';
            app.TabGroup3 = uitabgroup(app.BAICACommunicationModuleUIFigure);
            app.GrficoTab = uitab(app.TabGroup3);
            app.GrficoTab.Title = 'Gráfico';
            app.ax_graph = uiaxes(app.GrficoTab);
            zlabel(app.ax_graph, 'Z')
            app.ax_graph.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ax_graph.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.Tabela = uitab(app.TabGroup3);
            app.Tabela.Title = 'Tabela';
            app.table = uitable(app.Tabela);
            app.table.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.table.RowName = {};
            app.FLAMENGOBASKETBALLFLAMENGOLabel_3 = uilabel(app.BAICACommunicationModuleUIFigure);
            app.FLAMENGOBASKETBALLFLAMENGOLabel_3.Text = '      FLAMENGO BASKETBALL - FLAMENGO';
            app.FLAMENGOBASKETBALLFLAMENGOLabel_2 = uilabel(app.BAICACommunicationModuleUIFigure);
            app.FLAMENGOBASKETBALLFLAMENGOLabel_2.Text = '      FLAMENGO BASKETBALL - FLAMENGO';
            app.Label_4 = uilabel(app.BAICACommunicationModuleUIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.BAICACommunicationModuleUIFigure);
            app.Label_5.Text = '';
            app.CHATcomoBAICATextArea = uitextarea(app.BAICACommunicationModuleUIFigure);
            app.CHATcomoBAICATextArea.Tooltip = {'xcxvsdhshjsjh'};
            app.CHATcomoBAICATextAreaLabel = uilabel(app.BAICACommunicationModuleUIFigure);
            app.CHATcomoBAICATextAreaLabel.Text = 'CHAT com o BAICA';
            app.CHATcomoBAICATextAreaLabel_2 = uilabel(app.BAICACommunicationModuleUIFigure);
            app.CHATcomoBAICATextAreaLabel_2.Text = 'Respostas Gráficas';
            app.CHATcomoBAICATextAreaLabel_3 = uilabel(app.BAICACommunicationModuleUIFigure);
            app.CHATcomoBAICATextAreaLabel_3.Text = 'Insira seu login';
            app.user_field = uieditfield(app.BAICACommunicationModuleUIFigure, 'text');
            app.user_field.ValueChangingFcn = createCallbackFcn(app, @user_fieldValueChanging, true);
            % Show the figure after all components are created
            app.BAICACommunicationModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BAICA_Communication_Module_V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BAICACommunicationModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BAICACommunicationModuleUIFigure)
        end
    end
end